classdef ltelab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LTELabLTESimulatorAppUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ImportdataMenu                 matlab.ui.container.Menu
        MatlabArrayMenu                matlab.ui.container.Menu
        AsciifloatfileMenu             matlab.ui.container.Menu
        RandomdataMenu                 matlab.ui.container.Menu
        ExportdataMenu                 matlab.ui.container.Menu
        LoadexistingsetMenu            matlab.ui.container.Menu
        QuitMenu                       matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        ToolsMenu                      matlab.ui.container.Menu
        SetmodulatordemodulatorMenu    matlab.ui.container.Menu
        SetchannelMenu                 matlab.ui.container.Menu
        PlotMenu                       matlab.ui.container.Menu
        PlotdataMenu                   matlab.ui.container.Menu
        DatasetsMenu                   matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutLTELabMenu                matlab.ui.container.Menu
        LTELabTutorialMenu             matlab.ui.container.Menu
        EmailtheLTELabteamMenu         matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LTELabel                       matlab.ui.control.Label
        HeaderLabel                    matlab.ui.control.Label
    end

    
    properties (Access = public)
        LTE = LTE_dataframe();
    end
    
    properties (Access = private)
        DialogApp % Any secondary dialog being called
    end
    
    methods (Access = public)
        
        function updateLTE(app, LTE)
            app.LTE = LTE;
            app.redrawData();
        end

        function redrawData(app)
             app.HeaderLabel.Text = app.LTE.name;
        end

        function setToolbarState(app, edit, Tools, Plot)
            app.EditMenu.Enable = edit;
            app.ToolsMenu.Enable = Tools;
            app.PlotMenu.Enable = Plot;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: RandomdataMenu
        function cb_importrandomdata(app, event)
            app.DialogApp = pop_importrandomdata(app, app.LTE);
        end

        % Menu selected function: QuitMenu
        function cb_quit(app, event)
            delete(app.DialogApp);
            delete(app);
        end

        % Menu selected function: PlotdataMenu
        function cb_plotdata(app, event)
            app.DialogApp = pop_plotdata(app.LTE.data);
        end

        % Menu selected function: ExportdataMenu
        function cb_export(app, event)
            assignin("base","lte",app.LTE);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LTELabLTESimulatorAppUIFigure = uifigure('Visible', 'off');
            app.LTELabLTESimulatorAppUIFigure.Name = 'LTELab - LTE Simulator App';
            app.FileMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportdataMenu = uimenu(app.FileMenu);
            app.ImportdataMenu.Text = 'Import data';
            app.MatlabArrayMenu = uimenu(app.ImportdataMenu);
            app.MatlabArrayMenu.Text = 'Matlab Array';
            app.AsciifloatfileMenu = uimenu(app.ImportdataMenu);
            app.AsciifloatfileMenu.Text = 'Ascii/float file';
            app.RandomdataMenu = uimenu(app.ImportdataMenu);
            app.RandomdataMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_importrandomdata, true);
            app.RandomdataMenu.Text = 'Random data';
            app.ExportdataMenu = uimenu(app.FileMenu);
            app.ExportdataMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_export, true);
            app.ExportdataMenu.Text = 'Export data';
            app.LoadexistingsetMenu = uimenu(app.FileMenu);
            app.LoadexistingsetMenu.Text = 'Load existing set';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_quit, true);
            app.QuitMenu.Text = 'Quit';
            app.EditMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.EditMenu.Enable = 'off';
            app.EditMenu.Text = 'Edit';
            app.ToolsMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.ToolsMenu.Enable = 'off';
            app.ToolsMenu.Text = 'Tools';
            app.SetmodulatordemodulatorMenu = uimenu(app.ToolsMenu);
            app.SetmodulatordemodulatorMenu.Text = 'Set modulator/demodulator';
            app.SetchannelMenu = uimenu(app.ToolsMenu);
            app.SetchannelMenu.Text = 'Set channel';
            app.PlotMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.PlotMenu.Enable = 'off';
            app.PlotMenu.Text = 'Plot';
            app.PlotdataMenu = uimenu(app.PlotMenu);
            app.PlotdataMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_plotdata, true);
            app.PlotdataMenu.Text = 'Plot data';
            app.DatasetsMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.DatasetsMenu.Text = 'Datasets';
            app.HelpMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutLTELabMenu = uimenu(app.HelpMenu);
            app.AboutLTELabMenu.Text = 'About LTELab';
            app.LTELabTutorialMenu = uimenu(app.HelpMenu);
            app.LTELabTutorialMenu.Text = 'LTELab Tutorial';
            app.EmailtheLTELabteamMenu = uimenu(app.HelpMenu);
            app.EmailtheLTELabteamMenu.Text = 'Email the LTELab team';
            app.GridLayout = uigridlayout(app.LTELabLTESimulatorAppUIFigure);
            app.HeaderLabel = uilabel(app.GridLayout);
            app.HeaderLabel.Text = 'Header';
            app.LTELabel = uilabel(app.GridLayout);
            app.LTELabel.Text = 'LTE: 0';
            % Show the figure after all components are created
            app.LTELabLTESimulatorAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ltelab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LTELabLTESimulatorAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LTELabLTESimulatorAppUIFigure)
        end
    end
end